classdef BeatDetectionDlg < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        HeartRateCalculationSettingsPanel  matlab.ui.container.Panel
        ConvolutionWindowSizeEditField  matlab.ui.control.EditField
        ConvolutionWindowSizeEditFieldLabel  matlab.ui.control.Label
        CancelButton                    matlab.ui.control.StateButton
        RunButton                       matlab.ui.control.StateButton
        BeatDetectionSettingsPanel      matlab.ui.container.Panel
        ThresholdWindowsecEditField     matlab.ui.control.EditField
        ThresholdWindowsecLabel         matlab.ui.control.Label
        BeatMergeToleranceEditField     matlab.ui.control.EditField
        BeatMergeToleranceLabel         matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp % Description
    end
    
    methods (Access = public)
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.CallingApp = mainapp;
        end

        % Value changed function: RunButton
        function RunButtonValueChanged(app, event)
            GetBeatDetectOpts(app.CallingApp,str2num(app.BeatMergeToleranceEditField.Value), str2num(app.ThresholdWindowsecEditField.Value), str2num(app.ConvolutionWindowSizeEditField.Value))
            RunDetection(app.CallingApp)
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.BeatDetectionSettingsPanel = uipanel(app.UIFigure);
            app.BeatDetectionSettingsPanel.Title = 'Beat Detection Settings...';
            app.BeatMergeToleranceLabel = uilabel(app.BeatDetectionSettingsPanel);
            app.BeatMergeToleranceLabel.Text = 'Beat Merge Tolerance ';
            app.BeatMergeToleranceEditField = uieditfield(app.BeatDetectionSettingsPanel, 'text');
            app.BeatMergeToleranceEditField.Value = '1000';
            app.ThresholdWindowsecLabel = uilabel(app.BeatDetectionSettingsPanel);
            app.ThresholdWindowsecLabel.Text = 'Threshold Window (sec) ';
            app.ThresholdWindowsecEditField = uieditfield(app.BeatDetectionSettingsPanel, 'text');
            app.ThresholdWindowsecEditField.Value = '1';
            app.RunButton = uibutton(app.UIFigure, 'state');
            app.RunButton.ValueChangedFcn = createCallbackFcn(app, @RunButtonValueChanged, true);
            app.RunButton.Text = 'Run';
            app.CancelButton = uibutton(app.UIFigure, 'state');
            app.CancelButton.Text = 'Cancel';
            app.HeartRateCalculationSettingsPanel = uipanel(app.UIFigure);
            app.HeartRateCalculationSettingsPanel.Title = 'Heart Rate Calculation Settings...';
            app.ConvolutionWindowSizeEditFieldLabel = uilabel(app.HeartRateCalculationSettingsPanel);
            app.ConvolutionWindowSizeEditFieldLabel.Text = 'Convolution Window Size';
            app.ConvolutionWindowSizeEditField = uieditfield(app.HeartRateCalculationSettingsPanel, 'text');
            app.ConvolutionWindowSizeEditField.Value = '10';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BeatDetectionDlg(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end